classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        FilterModeButtonGroup         matlab.ui.container.ButtonGroup
        HighpassButton                matlab.ui.control.RadioButton
        BandpassButton                matlab.ui.control.RadioButton
        LowpassButton                 matlab.ui.control.RadioButton
        ConfirmButton                 matlab.ui.control.Button
        WindowFunctionButtonGroup     matlab.ui.container.ButtonGroup
        BlackmanButton                matlab.ui.control.RadioButton
        HanningButton                 matlab.ui.control.RadioButton
        HammingButton                 matlab.ui.control.RadioButton
        RectangleButton               matlab.ui.control.RadioButton
        RightPanel                    matlab.ui.container.Panel
        StopBand1EditField            matlab.ui.control.NumericEditField
        StopBand1EditFieldLabel       matlab.ui.control.Label
        HzLabel_5                     matlab.ui.control.Label
        HzLabel_4                     matlab.ui.control.Label
        PassBand1EditField            matlab.ui.control.NumericEditField
        PassBand1EditFieldLabel       matlab.ui.control.Label
        StopBandEditField             matlab.ui.control.NumericEditField
        StopBandEditFieldLabel        matlab.ui.control.Label
        HzLabel_3                     matlab.ui.control.Label
        HzLabel_2                     matlab.ui.control.Label
        HzLabel                       matlab.ui.control.Label
        SamplingFrequencyEditField    matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        PassBandEditField             matlab.ui.control.NumericEditField
        PassBandEditFieldLabel        matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        FilterModeEditField           matlab.ui.control.EditField
        FilterModeEditFieldLabel      matlab.ui.control.Label
        WindowFunctionEditField       matlab.ui.control.EditField
        WindowFunctionEditFieldLabel  matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {566, 566};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: ConfirmButton, RightPanel
        function ConfirmButtonPushed(app, event)
        switch app.WindowFunctionEditField.Value
            case 'Rectangle'
                app.Label.Text='Using Rectangle';
                switch app.FilterModeEditField.Value
                    case 'Bandpass'
                        app.Label_2.Text='Using Bandpass';
                        fs=app.SamplingFrequencyEditField.Value;
                   Ws=app.StopBandEditField.Value;
                         Wp=app.PassBandEditField.Value;
                         Ws3=app.StopBand1EditField.Value;
                         Wp3=app.PassBand1EditField.Value;
                        [outputafterLPF,f,originalfrequency,originaloutput]=Rectangular_Window_Bandpass_FIR(fs,Wp,Wp3,Ws,Ws3)
                         y=originaloutput;
                         x=originalfrequency;
                         app.UIAxes.XLabel.String='Hz';
                         app.UIAxes.Title.String='Original Signal Frequency Map';
                         plot(app.UIAxes,x,y)
                         y1=outputafterLPF;
                         x1=f;
                         app.UIAxes_2.XLabel.String='Hz';
                         app.UIAxes_2.Title.String='Processed Signal Frequency Map';
                         plot(app.UIAxes_2,x1,y1)
                    case 'Highpass'
                        app.Label_2.Text='Using Highpass';
                        fs=app.SamplingFrequencyEditField.Value;
                         Ws=app.StopBandEditField.Value;
                         Wp=app.PassBandEditField.Value;
                         Ws3=app.StopBand1EditField.Value;
                         Wp3=app.PassBand1EditField.Value;
                        [outputafterLPF,f,originalfrequency,originaloutput]=Rectangular_Highpass_FIR(fs,Wp,Wp3,Ws,Ws3)
                         y=originaloutput;
                         x=originalfrequency;
                         app.UIAxes.XLabel.String='Hz';
                         app.UIAxes.Title.String='Original Signal Frequency Map';
                         plot(app.UIAxes,x,y)
                         y1=outputafterLPF;
                         x1=f;
                         app.UIAxes_2.XLabel.String='Hz';
                         app.UIAxes_2.Title.String='Processed Signal Frequency Map';
                         plot(app.UIAxes_2,x1,y1)
                    case 'Lowpass'
                        app.Label_2.Text='Using Lowpass';
                         fs=app.SamplingFrequencyEditField.Value;
                         Ws=app.StopBandEditField.Value;
                         Wp=app.PassBandEditField.Value;
                         [outputafterLPF,f,originalfrequency,originaloutput]=Rectangular_Window_Lowpass_FIR(fs,Wp,Ws)
                         y=originaloutput;
                         x=originalfrequency;
                         app.UIAxes.XLabel.String='Hz';
                         app.UIAxes.Title.String='Original Signal Frequency Map';
                         plot(app.UIAxes,x,y)
                         y1=outputafterLPF;
                         x1=f;
                         app.UIAxes_2.XLabel.String='Hz';
                         app.UIAxes_2.Title.String='Processed Signal Frequency Map';
                         plot(app.UIAxes_2,x1,y1)


                end

            case 'Hamming'
                app.Label.Text='Using Hamming';
                switch app.FilterModeEditField.Value
                    case 'Bandpass'
                        app.Label_2.Text='Using Bandpass';
                        fs=app.SamplingFrequencyEditField.Value;
                         Ws=app.StopBandEditField.Value;
                         Wp=app.PassBandEditField.Value;
                         Ws3=app.StopBand1EditField.Value;
                         Wp3=app.PassBand1EditField.Value;
                        [outputafterLPF,f,originalfrequency,originaloutput]=Hamming_Window_Bandpass_FIR(fs,Wp,Wp3,Ws,Ws3)
                         y=originaloutput;
                         x=originalfrequency;
                         app.UIAxes.XLabel.String='Hz';
                         app.UIAxes.Title.String='Original Signal Frequency Map';
                         plot(app.UIAxes,x,y)
                         y1=outputafterLPF;
                         x1=f;
                         app.UIAxes_2.XLabel.String='Hz';
                         app.UIAxes_2.Title.String='Processed Signal Frequency Map';
                         plot(app.UIAxes_2,x1,y1)
                    case 'Highpass'
                        app.Label_2.Text='Using Highpass';
                        fs=app.SamplingFrequencyEditField.Value;
                         Ws=app.StopBandEditField.Value;
                         Wp=app.PassBandEditField.Value;
                         Ws3=app.StopBand1EditField.Value;
                         Wp3=app.PassBand1EditField.Value;
                        [outputafterLPF,f,originalfrequency,originaloutput]=Hamming_Window_Highpass_FIR(fs,Wp,Wp3,Ws,Ws3)
                         y=originaloutput;
                         x=originalfrequency;
                         app.UIAxes.XLabel.String='Hz';
                         app.UIAxes.Title.String='Original Signal Frequency Map';
                         plot(app.UIAxes,x,y)
                         y1=outputafterLPF;
                         x1=f;
                         app.UIAxes_2.XLabel.String='Hz';
                         app.UIAxes_2.Title.String='Processed Signal Frequency Map';
                         plot(app.UIAxes_2,x1,y1)
                    case 'Lowpass'
                        app.Label_2.Text='Using Lowpass';
                        fs=app.SamplingFrequencyEditField.Value;
                         Ws=app.StopBandEditField.Value;
                         Wp=app.PassBandEditField.Value;
                         [outputafterLPF,f,originalfrequency,originaloutput]=Hamming_Window_Lowpass_FIR(fs,Wp,Ws)
                         y=originaloutput;
                         x=originalfrequency;
                         app.UIAxes.XLabel.String='Hz';
                         app.UIAxes.Title.String='Original Signal Frequency Map';
                         plot(app.UIAxes,x,y)
                         y1=outputafterLPF;
                         x1=f;
                         app.UIAxes_2.XLabel.String='Hz';
                         app.UIAxes_2.Title.String='Processed Signal Frequency Map';
                         plot(app.UIAxes_2,x1,y1)


                end
            case 'Hanning'
                app.Label.Text='Using hanning';
                switch app.FilterModeEditField.Value
                    case 'Bandpass'
                        app.Label_2.Text='Using Bandpass';
                        fs=app.SamplingFrequencyEditField.Value;
                         Ws=app.StopBandEditField.Value;
                         Wp=app.PassBandEditField.Value;
                         Ws3=app.StopBand1EditField.Value;
                         Wp3=app.PassBand1EditField.Value;
                        [outputafterLPF,f,originalfrequency,originaloutput]=Hanning_Window_Bandpass_FIR(fs,Wp,Wp3,Ws,Ws3)
                         y=originaloutput;
                         x=originalfrequency;
                         app.UIAxes.XLabel.String='Hz';
                         app.UIAxes.Title.String='Original Signal Frequency Map';
                         plot(app.UIAxes,x,y)
                         y1=outputafterLPF;
                         x1=f;
                         app.UIAxes_2.XLabel.String='Hz';
                         app.UIAxes_2.Title.String='Processed Signal Frequency Map';
                         plot(app.UIAxes_2,x1,y1);
                    case 'Highpass'
                        app.Label_2.Text='Using Highpass';
                        fs=app.SamplingFrequencyEditField.Value;
                         Ws=app.StopBandEditField.Value;
                         Wp=app.PassBandEditField.Value;
                         Ws3=app.StopBand1EditField.Value;
                         Wp3=app.PassBand1EditField.Value;
                        [outputafterLPF,f,originalfrequency,originaloutput]=Hanning_Window_Highpass_FIR(fs,Wp,Wp3,Ws,Ws3)
                         y=originaloutput;
                         x=originalfrequency;
                         app.UIAxes.XLabel.String='Hz';
                         app.UIAxes.Title.String='Original Signal Frequency Map';
                         plot(app.UIAxes,x,y)
                         y1=outputafterLPF;
                         x1=f;
                         app.UIAxes_2.XLabel.String='Hz';
                         app.UIAxes_2.Title.String='Processed Signal Frequency Map';
                         plot(app.UIAxes_2,x1,y1)
                    case 'Lowpass'
                        app.Label_2.Text='Using Lowpass';
                        fs=app.SamplingFrequencyEditField.Value;
                         Ws=app.StopBandEditField.Value;
                         Wp=app.PassBandEditField.Value;
                         [outputafterLPF,f,originalfrequency,originaloutput]=Hanning_Window_Lowpass_FIR(fs,Wp,Ws)
                         y=originaloutput;
                         x=originalfrequency;
                         app.UIAxes.XLabel.String='Hz';
                         app.UIAxes.Title.String='Original Signal Frequency Map';
                         plot(app.UIAxes,x,y)
                         y1=outputafterLPF;
                         x1=f;
                         app.UIAxes_2.XLabel.String='Hz';
                         app.UIAxes_2.Title.String='Processed Signal Frequency Map';
                         plot(app.UIAxes_2,x1,y1)


                end
            case 'Blackman'
                app.Label.Text='Using Blackman';
                 switch app.FilterModeEditField.Value
                    case 'Bandpass'
                        app.Label_2.Text='Using Bandpass';
                        fs=app.SamplingFrequencyEditField.Value;
                         Ws=app.StopBandEditField.Value;
                         Wp=app.PassBandEditField.Value;
                         Ws3=app.StopBand1EditField.Value;
                         Wp3=app.PassBand1EditField.Value;
                        [outputafterLPF,f,originalfrequency,originaloutput]=Blackman_Window_Bandpass_FIR(fs,Wp,Wp3,Ws,Ws3)
                         y=originaloutput;
                         x=originalfrequency;
                         app.UIAxes.XLabel.String='Hz';
                         app.UIAxes.Title.String='Original Signal Frequency Map';
                         plot(app.UIAxes,x,y)
                         y1=outputafterLPF;
                         x1=f;
                         app.UIAxes_2.XLabel.String='Hz';
                         app.UIAxes_2.Title.String='Processed Signal Frequency Map';
                         plot(app.UIAxes_2,x1,y1)
                    case 'Highpass'
                        app.Label_2.Text='Using Highpass';
                        fs=app.SamplingFrequencyEditField.Value;
                         Ws=app.StopBandEditField.Value;
                         Wp=app.PassBandEditField.Value;
                         Ws3=app.StopBand1EditField.Value;
                         Wp3=app.PassBand1EditField.Value;
                        [outputafterLPF,f,originalfrequency,originaloutput]=Blackman_Window_Highpass_FIR(fs,Wp,Wp3,Ws,Ws3)
                         y=originaloutput;
                         x=originalfrequency;
                         app.UIAxes.XLabel.String='Hz';
                         app.UIAxes.Title.String='Original Signal Frequency Map';
                         plot(app.UIAxes,x,y)
                         y1=outputafterLPF;
                         x1=f;
                         app.UIAxes_2.XLabel.String='Hz';
                         app.UIAxes_2.Title.String='Processed Signal Frequency Map';
                         plot(app.UIAxes_2,x1,y1)
                    case 'Lowpass'
                        app.Label_2.Text='Using Lowpass';
                         fs=app.SamplingFrequencyEditField.Value;
                         Ws=app.StopBandEditField.Value;
                         Wp=app.PassBandEditField.Value;
                         [outputafterLPF,f,originalfrequency,originaloutput]=Blackman_Window_Lowpass_FIR(fs,Wp,Ws)
                         y=originaloutput;
                         x=originalfrequency;
                         app.UIAxes.XLabel.String='Hz';
                         app.UIAxes.Title.String='Original Signal Frequency Map';
                         plot(app.UIAxes,x,y)
                         y1=outputafterLPF;
                         x1=f;
                         app.UIAxes_2.XLabel.String='Hz';
                         app.UIAxes_2.Title.String='Processed Signal Frequency Map';
                         plot(app.UIAxes_2,x1,y1)


                 end
        end
        end

        % Selection changed function: WindowFunctionButtonGroup
        function WindowFunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.WindowFunctionButtonGroup.SelectedObject;
            switch   selectedButton.Text
                case 'Rectangle'
                    app.WindowFunctionEditField.Value='Rectangle';
                case 'Hamming'
                    app.WindowFunctionEditField.Value='Hamming';
                case 'Hanning'
                    app.WindowFunctionEditField.Value='Hanning';
                case 'Blackman'
                    app.WindowFunctionEditField.Value='Blackman';   
                
            end
            
            
        end

        % Selection changed function: FilterModeButtonGroup
        function FilterModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterModeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Highpass'
                    app.FilterModeEditField.Value='Highpass';
                case 'Lowpass'
                    app.FilterModeEditField.Value='Lowpass';
                case 'Bandpass'
                    app.FilterModeEditField.Value='Bandpass';
            end
        end

        % Value changed function: PassBandEditField
        function PassBandEditFieldValueChanged(app, event)
            value = app.PassBandEditField.Value;
            
        end

        % Value changed function: SamplingFrequencyEditField
        function SamplingFrequencyEditFieldValueChanged(app, event)
            value = app.SamplingFrequencyEditField.Value;
            
        end

        % Value changed function: StopBandEditField
        function StopBandEditFieldValueChanged(app, event)
            value = app.StopBandEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WindowFunctionButtonGroup = uibuttongroup(app.LeftPanel);
            app.WindowFunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WindowFunctionButtonGroupSelectionChanged, true);
            app.WindowFunctionButtonGroup.Title = 'Window Function';
            app.RectangleButton = uiradiobutton(app.WindowFunctionButtonGroup);
            app.RectangleButton.Text = 'Rectangle';
            app.RectangleButton.Value = true;
            app.HammingButton = uiradiobutton(app.WindowFunctionButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.HanningButton = uiradiobutton(app.WindowFunctionButtonGroup);
            app.HanningButton.Text = 'Hanning';
            app.BlackmanButton = uiradiobutton(app.WindowFunctionButtonGroup);
            app.BlackmanButton.Text = 'Blackman';
            app.ConfirmButton = uibutton(app.LeftPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.FilterModeButtonGroup = uibuttongroup(app.LeftPanel);
            app.FilterModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterModeButtonGroupSelectionChanged, true);
            app.FilterModeButtonGroup.Title = 'Filter Mode';
            app.LowpassButton = uiradiobutton(app.FilterModeButtonGroup);
            app.LowpassButton.Text = 'Lowpass';
            app.LowpassButton.Value = true;
            app.BandpassButton = uiradiobutton(app.FilterModeButtonGroup);
            app.BandpassButton.Text = 'Bandpass';
            app.HighpassButton = uiradiobutton(app.FilterModeButtonGroup);
            app.HighpassButton.Text = 'Highpass';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.ButtonDownFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_2, 'Z')
            app.WindowFunctionEditFieldLabel = uilabel(app.RightPanel);
            app.WindowFunctionEditFieldLabel.Text = 'Window Function ';
            app.WindowFunctionEditField = uieditfield(app.RightPanel, 'text');
            app.WindowFunctionEditField.Value = 'Rectangle';
            app.FilterModeEditFieldLabel = uilabel(app.RightPanel);
            app.FilterModeEditFieldLabel.Text = 'Filter Mode ';
            app.FilterModeEditField = uieditfield(app.RightPanel, 'text');
            app.FilterModeEditField.Value = 'Lowpass';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '';
            app.PassBandEditFieldLabel = uilabel(app.RightPanel);
            app.PassBandEditFieldLabel.Text = 'Pass Band';
            app.PassBandEditField = uieditfield(app.RightPanel, 'numeric');
            app.PassBandEditField.ValueChangedFcn = createCallbackFcn(app, @PassBandEditFieldValueChanged, true);
            app.SamplingFrequencyEditFieldLabel = uilabel(app.RightPanel);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.RightPanel, 'numeric');
            app.SamplingFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyEditFieldValueChanged, true);
            app.HzLabel = uilabel(app.RightPanel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.RightPanel);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.RightPanel);
            app.HzLabel_3.Text = 'Hz';
            app.StopBandEditFieldLabel = uilabel(app.RightPanel);
            app.StopBandEditFieldLabel.Text = 'Stop Band';
            app.StopBandEditField = uieditfield(app.RightPanel, 'numeric');
            app.StopBandEditField.ValueChangedFcn = createCallbackFcn(app, @StopBandEditFieldValueChanged, true);
            app.PassBand1EditFieldLabel = uilabel(app.RightPanel);
            app.PassBand1EditFieldLabel.Text = 'Pass Band1';
            app.PassBand1EditField = uieditfield(app.RightPanel, 'numeric');
            app.HzLabel_4 = uilabel(app.RightPanel);
            app.HzLabel_4.Text = 'Hz';
            app.HzLabel_5 = uilabel(app.RightPanel);
            app.HzLabel_5.Text = 'Hz';
            app.StopBand1EditFieldLabel = uilabel(app.RightPanel);
            app.StopBand1EditFieldLabel.Text = 'Stop Band1';
            app.StopBand1EditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end